classdef TiffToMat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImportFilesTab                 matlab.ui.container.Tab
        importPanel                    matlab.ui.container.Panel
        setDirectoryButton             matlab.ui.control.Button
        importButton                   matlab.ui.control.Button
        feedbackLabel                  matlab.ui.control.Label
        StepLabel                      matlab.ui.control.Label
        StepLabel_2                    matlab.ui.control.Label
        StepLabel_6                    matlab.ui.control.Label
        uipanel3                       matlab.ui.container.Panel
        registerButton                 matlab.ui.control.Button
        StepLabel_4                    matlab.ui.control.Label
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        UpdateMeanProjButton           matlab.ui.control.Button
        StepLabel_7                    matlab.ui.control.Label
        importPanel_2                  matlab.ui.container.Panel
        imageAxis                      matlab.ui.control.UIAxes
        ExtrasPanel                    matlab.ui.container.Panel
        skipFactorEditField            matlab.ui.control.EditField
        SkipByLabel                    matlab.ui.control.Label
        pickFrameLabel                 matlab.ui.control.Label
        firstImageEditField            matlab.ui.control.EditField
        startNumLabel                  matlab.ui.control.Label
        startToLabel                   matlab.ui.control.Label
        endImageEditField              matlab.ui.control.EditField
        saveEntryEditField             matlab.ui.control.EditField
        StepLabel_5                    matlab.ui.control.Label
        MakeROIsTab                    matlab.ui.container.Tab
        uipanel11                      matlab.ui.container.Panel
        highCutEntry                   matlab.ui.control.EditField
        feedbackString                 matlab.ui.control.Label
        LoadMeanProjButton             matlab.ui.control.Button
        drawassistCheckBox             matlab.ui.control.CheckBox
        uipanel6                       matlab.ui.container.Panel
        somaButton                     matlab.ui.control.Button
        dendriteButton                 matlab.ui.control.Button
        axonButton                     matlab.ui.control.Button
        somaticCheckBox                matlab.ui.control.CheckBox
        axonalCheckBox                 matlab.ui.control.CheckBox
        dendriticCheckBox              matlab.ui.control.CheckBox
        MAXKnobLabel                   matlab.ui.control.Label
        MAXKnob                        matlab.ui.control.Knob
        ImageWindow                    matlab.ui.control.UIAxes
        imageHistogram                 matlab.ui.control.UIAxes
        ExtractFluorescenceTab         matlab.ui.container.Tab
        uipanel1                       matlab.ui.container.Panel
        extractFeedbackString          matlab.ui.control.Label
        somaExtractCheck               matlab.ui.control.CheckBox
        dendriteExtractCheck           matlab.ui.control.CheckBox
        axonExtractCheck               matlab.ui.control.CheckBox
        extractButton                  matlab.ui.control.Button
        uipanel9                       matlab.ui.container.Panel
        slidingBaselineBtn             matlab.ui.control.Button
        useQuantForDFToggle            matlab.ui.control.CheckBox
        typeSelectorMenu               matlab.ui.control.DropDown
        feedbackString_2               matlab.ui.control.Label
        MoveBaselineWinEntry           matlab.ui.control.NumericEditField
        uipanel20                      matlab.ui.container.Panel
        traceDisplay                   matlab.ui.control.UIAxes
        yHighTrace                     matlab.ui.control.EditField
        yLowTrace                      matlab.ui.control.EditField
        YmaxLabel                      matlab.ui.control.Label
        YminLabel                      matlab.ui.control.Label
        uipanel5                       matlab.ui.container.Panel
        somaticROIs_DisplayToggle      matlab.ui.control.CheckBox
        dendriticROIs_DisplayToggle    matlab.ui.control.CheckBox
        axonalROIs_DisplayToggle       matlab.ui.control.CheckBox
        plotAsDFToggle                 matlab.ui.control.CheckBox
        uipanel7                       matlab.ui.container.Panel
        corAxis                        matlab.ui.control.UIAxes
        showCorrelatedToggle           matlab.ui.control.CheckBox
        corThresholdEntry              matlab.ui.control.EditField
        text9                          matlab.ui.control.Label
        pushbutton38                   matlab.ui.control.Button
        SaveTab                        matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        DateYYMMDDLabel                matlab.ui.control.Label
        DateYYMMDDEditField            matlab.ui.control.EditField
        YourInitialsEditFieldLabel     matlab.ui.control.Label
        YourInitialsEditField          matlab.ui.control.EditField
        SpeciesHuMsRtEditFieldLabel    matlab.ui.control.Label
        SpeciesHuMsRtEditField         matlab.ui.control.EditField
        SexMFXLabel                    matlab.ui.control.Label
        SexMFXEditField                matlab.ui.control.EditField
        Size2K4K8KEditFieldLabel       matlab.ui.control.Label
        Size2K4K8KEditField            matlab.ui.control.EditField
        DaysInVitroLabel               matlab.ui.control.Label
        DaysInVitroEditField           matlab.ui.control.EditField
        SampleNumberEditFieldLabel     matlab.ui.control.Label
        SampleNumberEditField          matlab.ui.control.EditField
        ConditionEditFieldLabel        matlab.ui.control.Label
        ConditionEditField             matlab.ui.control.EditField
        DetectMetaDataButton           matlab.ui.control.Button
        DurationofRecordingsEditFieldLabel  matlab.ui.control.Label
        DurationofRecordingsEditField  matlab.ui.control.EditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.EditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.EditField
        ofFramesEditField_2Label       matlab.ui.control.Label
        ofFramesEditField              matlab.ui.control.EditField
        AFTERsavingdataclearallPanel   matlab.ui.container.Panel
        AreyousureCheckBox             matlab.ui.control.CheckBox
        CLEARALLVARIABLESButton        matlab.ui.control.Button
        ThisactioncannotbeundoneLabel  matlab.ui.control.Label
        WillDisplaybelowIfneededfixbeforesavingLabel  matlab.ui.control.Label
        SaveMetaDatafieldsastheyappearaboveLabel  matlab.ui.control.Label
        SaveFeedbackString             matlab.ui.control.Label
        HelpTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        WorkspacePanel                 matlab.ui.container.Panel
        workspaceVarListBox            matlab.ui.control.ListBox
        refreshVarListButton           matlab.ui.control.Button
        deleteSelectionBtn             matlab.ui.control.Button
        uipanel13                      matlab.ui.container.Panel
        roiSelector                    matlab.ui.control.ListBox
        deleteSelectedROI              matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        mpTifInfo % Description
        returnTypeStrings %strings 'somatic / dendritic / axonal'
        typeAllColor % The color I will plot these ROIs in
        meanProj_importedStack %holds the mean projection between pages
        mPiS_ImOb %the image object of meanProj_importedStack
        PlotWidths %widths of lines to plot
        quantileCutoffs %for quantile cutoff function
        baselineValues %
        fBLs %for baseline function
        CurrentName %string representing the file name
    end
    
    methods (Access = private)
                   
        function generateMeanProj(app)
            s=evalin('base','importedStack');
            mP=mean(s,3);
            meanProj = 'meanProj_importedStack';
            
            if strcmp(class(s),'uint16') %#ok<STISA> 
                evalin('base','clear meanProj_importedStack')
                assignin('base',meanProj,im2uint16(mP,'Indexed'));
            else
                assignin('base',meanProj,double(mP));
            end
            
            imagesc(app.imageAxis,uint16(mP));
            xlims = evalin('base','metaData.mpTifInfo(1).Width');
            app.imageAxis.XLim = [1 xlims];
        end
        
        function freehandROI(app, typeString)%somatic, dendritic, or axonal
            
            %% turn off all zoom/pan while drawing the ROI
            zoom(app.ImageWindow,'off');
            pan(app.ImageWindow,'off');

            
            %% see if we already have ROIs of this type.
            %% if yes, make one more. If no, initialize the type.
            typeString = char(typeString);
            g=evalin('base',['exist(' '''' typeString ''  'RoiCounter'')']);
            if g==1
                h=evalin('base',[typeString 'RoiCounter']);
                r=evalin('base',[typeString 'ROIs']);
                c=evalin('base',[typeString 'ROICenters']);
                b=evalin('base',[typeString 'ROIBoundaries']);
                pl=evalin('base',[typeString 'ROI_PixelLists']);
        
                h=h+1;
                if app.drawassistCheckBox.Value == 1
                    a=drawassisted(app.mPiS_ImOb);
                else
                    a=drawfreehand(app.ImageWindow);
                end
                a.InteractionsAllowed = 'none';
                mask=createMask(a,app.mPiS_ImOb);
        
        
                r{h}=mask;
                b{h}=bwboundaries(mask);
                c{h}=regionprops(mask,'Centroid');
                pl{h}=regionprops(mask,'PixelList');
        
        
                assignin('base',[typeString 'ROIs'],r)
                assignin('base',[typeString 'ROICenters'],c)
                assignin('base',[typeString 'ROIBoundaries'],b)
                assignin('base',[typeString 'RoiCounter'],h)
                assignin('base',[typeString 'ROI_PixelLists'],pl)
                assignin('base','roiSelectString',typeString)
        
            else
                h=1;
                
                if app.drawassistCheckBox.Value == 1
                    a=drawassisted(app.mPiS_ImOb);
                else
                    a=drawfreehand(app.ImageWindow);
                end
                
                a.InteractionsAllowed = 'none';
                mask=createMask(a,app.mPiS_ImOb);
        
                assignin('base',[typeString 'ROIs'],{mask})
                assignin('base',[typeString 'ROICenters'],{regionprops(mask,'Centroid')})
                assignin('base',[typeString 'ROIBoundaries'],{bwboundaries(mask)})
                assignin('base',[typeString 'RoiCounter'],h)
                assignin('base',[typeString 'ROI_PixelLists'],{regionprops(mask,'PixelList')})
                assignin('base','roiSelectString',typeString)
        
            end

            
            %refresh our variable lists and display ROIs
            roisDisplayToggle(app,1);
            refreshVarListButtonPushed(app);
            pan(app.ImageWindow,'on');
        end
            
        function roisDisplayToggle(app,~) %depends on whether the switches are on
        
            if nargin==1
                LoadMeanProjButtonPushed(app);
            end
    
            % this executes for all
            hold(app.ImageWindow,'on');
            returnAllTypes(app);
            % reset the box
           
            % see who is (still) on
            for n=1:numel(app.returnTypeStrings)
                checktype = char(app.returnTypeStrings{n});
                whoIsOn(n)=eval(['app.' checktype 'CheckBox.Value;']); %#ok<AGROW> 
            end
            whoIsOn=find(whoIsOn==1);
    
            roiCounter=0;
            for rI=1:numel(whoIsOn)
                typeString=char(app.returnTypeStrings{whoIsOn(rI)});
                g=evalin('base',['exist(''' typeString 'ROICenters'');']);
                if g==1
                    c=evalin('base',[typeString 'ROICenters']);
                    b=evalin('base',[typeString 'ROIBoundaries']);
                    if numel(c) ~= 0
                        plotRange=1:numel(c);
                        allCount=numel(c);
    
                        for n=1:allCount
                            roiCounter=roiCounter+1;
                            roisList{roiCounter}=[typeString '_' num2str(n)]; %#ok<AGROW> 
                        end
     
    %                         app.roiSelector.Value = '';
                    app.roiSelector.Items = roisList;
                    
                    % Plot
                    outColor=app.typeAllColor{whoIsOn(rI)};
                    txtColor=[1 1 1];
                    hold(app.ImageWindow,'on')
                    for n=1:numel(plotRange)
                        for k=1:numel(c{1,n})
                            plot(app.ImageWindow,b{1,n}{k,1}(:,2),b{1,n}{k,1}(:,1),'Color',...
                                outColor,'LineWidth',2)
                            text(app.ImageWindow,c{1,n}(k).Centroid(1)-1, c{1,n}(k).Centroid(2),...
                                num2str(plotRange(n)),'FontSize',14,'FontWeight','Bold','Color',txtColor);
                        end
                    end
                    
                        hold(app.ImageWindow,'off')
                    end
                end
            end

            refreshVarListButtonPushed(app);
        end
        
        function returnAllTypes(app)
            % set all known ROI types here
            app.returnTypeStrings={'somatic','dendritic','axonal'};
            app.typeAllColor={[0,0.8,0.3],[0,0,1],[1,0,1]};
            app.PlotWidths = [1,1,1];
        end
        
        function deleteROI(app,numberList,typeList)

        % deletes all elements associated with program created ROIs
        %
        % 1) 'numberList' is a vector of roiID numbers e.g. (101,99)
        % 2) 'typeList' is a cell array of roiType strings e.g. {'somatic','dendritic'}
        %
        % cdeister@brown.edu; 10/8/2017

            a=evalin('base','who');
            
            % check to see if they have rois of the right type and delete the core
            % first.
            uniqueTypes=unique(typeList);
            
            
            for n=1:numel(uniqueTypes)
                h=evalin('base',['exist(' '''' uniqueTypes{n} 'ROIs'')']);
                if h==1
%                     p = length(uniqueTypes{n});
                    roiCount=evalin('base',['numel(' uniqueTypes{n} 'ROIs)']);
                    if roiCount~=0
                        roisToDelete=numberList(strcmp(typeList,uniqueTypes{n}));
                        jj=a(startsWith(a,uniqueTypes{n}));
                        cS1=[uniqueTypes{n} 'ROI'];
                        cS2=[uniqueTypes{n} 'Roi'];
                    
                        coreStrings=vertcat(jj(startsWith(jj,cS1)),jj(startsWith(jj,cS2)));
                        
%                         numelTypeVars=numel(jj);
%                         coreVarDiff=numelTypeVars-5;
                        nonCoreStrings=setdiff(jj,coreStrings);
                        
                        for j=1:numel(nonCoreStrings)
                            dim=evalin('base',['find(size(' nonCoreStrings{j} ')==' num2str(roiCount) ');']);
                            if dim ==1
                                evalin('base',[nonCoreStrings{j} '([' num2str(roisToDelete) '],:)=[];']);
                            elseif dim ==2
                                evalin('base',[nonCoreStrings{j} '(:,[' num2str(roisToDelete) '])=[];']);
                            else
                            end    
                        end
            
                        evalin('base',[uniqueTypes{n} 'ROI_PixelLists([' num2str(roisToDelete) '])=[];'])
                        evalin('base',[uniqueTypes{n} 'ROIBoundaries([' num2str(roisToDelete) '])=[];'])
                        evalin('base',[uniqueTypes{n} 'ROICenters([' num2str(roisToDelete) '])=[];'])
                        evalin('base',[uniqueTypes{n} 'ROIs([' num2str(roisToDelete) '])=[];'])
                        evalin('base',[uniqueTypes{n} 'RoiCounter=numel(' uniqueTypes{n} 'ROIs);'])
                    else
                        disp('no rois to delete')
                    end
                else
                end
            end
            
            refreshVarListButtonPushed(app)
            end
        
        function genericDisplayTypeToggle(app)
            
            returnAllTypes(app);
            whoIsOn = zeros(1,numel(app.returnTypeStrings));
            for n=1:numel(app.returnTypeStrings)
                whoIsOn(n)=eval(['get(app.' app.returnTypeStrings{n} 'ROIs_DisplayToggle, ''Value'');']);
            end
        
            whoIsOn=find(whoIsOn==1);
        
%                 if numel(whoIsOn)==0
%                     app.roiSelector.Value = 'Rois';
%                 end
        
            roiCounter=0;
            for rI=1:numel(whoIsOn)
                typeString=app.returnTypeStrings{whoIsOn(rI)};
                gf=checkForWSVar([typeString 'F']);
                gdf=checkForWSVar([typeString 'F_DF']);
                if gf==1 || gdf==1
                    h=evalin('base',[typeString 'RoiCounter']);
                    % Populate the box:
                    for n=1:h
                        roiCounter=roiCounter+1;
                        roisList{roiCounter}=[typeString '_' num2str(n)];
                    end
                    app.roiSelector.Items = roisList;
%                     app.roiSelector.Value=roisList(1);
                else
                    disp(['No ROIs of type ' typeString ' have been extracted'])
                end
            end
        end

        function computeQuantileCutoffs(app,dataMatToCut,startVal,minVal,~)

            % Computes a 'ranked' quantile cutoff for data baselining purposes. 
            % Some data have more variance than others, yet in a population recording, without groundtruth, we have to estimate the baseline.
            % The goal is to estimate the mean of the 'noise floor' in a way that accounts for variable skew.
            % Arguments: dataMatToCut (required) is your data, which can be 1d or 2d. startVal and minVal are optional args that constrain
            % the range of valid quantile cutoffs. By default, startVal is 0.25 and minVal is 0.05. dimToCut is another optional argument,
            % which tells it which dimension is the time dimension.
            % Products: quantileCutoffs is a list of quantiles that is as long as the non-time dimension of your data. This can be used to 
            % baseline your data (with a different function or method). baselineValues are the static baseline value that is the quantile you
            % computed. This can be used to directly baseline your data. 
            %
            % "algorithm" for quantile choice: Chris Deister
            % cdeister@brown.edu with questions
            
            if nargin<3
                startVal=0.25;
                minVal=0.05;
                [~,cutDim]=min(size(dataMatToCut));
            end
            
            if cutDim==1
                dataMatToCut=dataMatToCut';
            else
            end
            
            
            for n=1:size(dataMatToCut,2)
                
                tScore=(quantile(dataMatToCut(:,n),0.5)-quantile(dataMatToCut(:,n),startVal))./quantile(dataMatToCut(:,n),0.5);
                qCut=(startVal-tScore);
                
                if qCut<minVal
                    qCut=minVal;
                else
                end
                
                app.quantileCutoffs(n)=qCut;
                app.baselineValues(n)=quantile(dataMatToCut(:,n),qCut);
            end
        end

        function slidingBaseline(app,data,windowSize,quantileThresh)  
            %pre-allocate the vector and then map the data into the pieces between the
            %window fragments, then map the first window/2 values into the first pad
            %and the last window/2 values into the last pad.
%             BL=zeros(size(data));
            
            if mod(windowSize,2)
                windowSize=windowSize+1;
            else
            end
            
            halfInd=fix(windowSize/2);
           
            padD=zeros(size(data,1),size(data,2)+windowSize);
            disp(size(padD))
            
            padD(:,halfInd+1:end-halfInd)=data;
            padD(:,1:halfInd)=data(:,halfInd+1:(halfInd+1)+(halfInd-1));
            padD(:,end-(halfInd-1):end)=data(:,end-(halfInd-1):end);
            disp(size(padD))
            
            startQuant=halfInd;
            if size(data,1)>1
                for n=1:size(data,2)
                    td=padD(:,(n+startQuant)-startQuant:(n+startQuant)+(startQuant-1));
                    aa=quantile(td,quantileThresh,2);
                    app.fBLs(:,n)=diag(aa);
                end
            else
                for n=1:size(data,2)
                    td=padD(:,(n+startQuant)-startQuant:(n+startQuant)+(startQuant-1));
                    aa=quantile(td,quantileThresh);
                    app.fBLs(:,n)=aa;
                end
            end
        end

        function plotROI(app)
            
            % main roi load
            returnAllTypes(app);
            mainROIString=app.roiSelector.Value;
            allROIStrings=app.roiSelector.Items;
            totalROIs=numel(allROIStrings);
            mainROIStringSplit=strsplit(mainROIString,'_');
            mainType=mainROIStringSplit{1};
            mainTypeID=fix(str2double(mainROIStringSplit{2}));
            
            % plot main trace
            pltDF=app.plotAsDFToggle.Value;
            try 
                evalin('base',['exist("' mainType 'F_DF");'])
                
                if pltDF == 1
                    mainTrace=evalin('base',[mainType 'F_DF(' num2str(mainTypeID) ',:);']);
                else
                    mainTrace=evalin('base',[mainType 'F(' num2str(mainTypeID) ',:);']);
                end
                
                try
                    timeVec=evalin('base','imTime');
                catch
                    timeVec=1:numel(mainTrace);
                end
                
                yLow=str2num(app.yLowTrace.Value);
                yHigh=str2num(app.yHighTrace.Value);
                
                hold(app.traceDisplay,'off');
                mainColor=[0,0,0];
                plot(app.traceDisplay, timeVec(1:numel(mainTrace(1,:)')),mainTrace(1,:)','Color',mainColor,'LineWidth',1.1);
                h=app.traceDisplay.Children;
                if numel(h)>1
                    for k=2:numel(h)
                        h(k).Color=[1,0,0];
                    end
                end
                app.traceDisplay.YLim = [yLow yHigh];
                
                
                % plot related traces
                plotRelated=app.showCorrelatedToggle.Value;
                relatedThreshold=str2double(app.corThresholdEntry.Value);
                if plotRelated == 1
                    % get all traces and masks of the types selected
                    allTypes=app.returnTypeStrings;
                    whoIsOn = zeros(1,numel(allTypes));
                    for n=1:numel(allTypes)
                        whoIsOn(n)=eval(['app.' allTypes{n} 'ROIs_DisplayToggle.Value;']);
                    end
                    
                    whoIsOn=find(whoIsOn==1);
                    tracesToGrab=eval(['allTypes([' num2str(whoIsOn) ']);']);
                    allTraces=[];
                    allROIMasks=[];
                    allROICentroids=[];
                    for k=1:numel(tracesToGrab)
                        pltDF=app.plotAsDFToggle.Value;
                        if pltDF == 1
                            allTraces=vertcat(allTraces,evalin('base',[tracesToGrab{k} 'F_DF;']));
                        else
                            allTraces=vertcat(allTraces,evalin('base',[tracesToGrab{k} 'F;']));
                        end
                            allROIMasks=horzcat(allROIMasks,evalin('base',[tracesToGrab{k} 'ROIs;']));
                            allROICentroids=horzcat(allROICentroids,evalin('base',[tracesToGrab{k} 'ROICenters;']));
                    end
                    
                    % compute the correlations between the primary and the rest
                    curCorr=corr(repmat(mainTrace,size(allTraces,1),1)',allTraces');
                    
                    plot(app.corAxis, curCorr','k-','linewidth',0.5)
                    hold(app.corAxis,'on');
                    plot(app.corAxis,[1 totalROIs],[relatedThreshold relatedThreshold],'r:','LineWidth',1)
                    hold(app.corAxis, 'off');
                    app.corAxis.YLim = [-0.1,1.1];
                    
                    [csV,csI]=sort(curCorr','descend');
                    csV=csV(:,1);
                    csI=csI(:,1);
                    
                    % threshold the correlations and plot the values
                    relatedROIs=csI(csV>=relatedThreshold);
                    relatedCorrelations=csV(csV>=relatedThreshold);
                    
                    hold(app.corAxis, 'on');
                    scatter(app.corAxis, relatedROIs,relatedCorrelations,'filled','MarkerEdgeColor',[0 0 0],...
                        'MarkerFaceColor',[0.0,0.5,1.0],'SizeData',70)                     
                    
                    % allROIStrings
                    
                    % shrink the total traces to the related ones (including
                    % primary)
                    % keep the traces of interest
                    % the primary is in all, but this is convinent.
                    primarySubgroupID=find(relatedROIs==mainROIString);
                    nonPrimarySubgroupID=setdiff(1:numel(relatedROIs),primarySubgroupID);
                    
                    allTraces=allTraces(relatedROIs,:);
                    
                    % now get the roi types for all realted
                    allRelatedTypeStrings=allROIStrings(relatedROIs);
                    for n=1:numel(allRelatedTypeStrings)
                        tSplt=strsplit(allRelatedTypeStrings{n},'_');
                        tAcum(n)=tSplt(1);
                    end
                    
                    allRelatedTypeStrings=tAcum;
    %                 primaryTypeString=allRelatedTypeStrings(primarySubgroupID);
                    nonPrimaryTypeString=allRelatedTypeStrings(nonPrimarySubgroupID);
            
                    maxes = numel(nonPrimarySubgroupID);
                    % plot the related traces exclude the primary as we've plotted it.
                    % add to accumulating h's for making figures etc.
                    aa = jet(maxes+1); %then go through the rainbow
                    aa(1,:) = [0 0 0]; %first line is black
                    
                    for n=1:maxes
                        % allColors{strcmp(allTypes,nonPrimaryTypeString{n})},
                        g=aa(n,:);
                        hold(app.traceDisplay,'on')
                        h(n)=plot(app.traceDisplay, timeVec,allTraces(nonPrimarySubgroupID(n),:)','Color',g,...
                            'LineWidth',app.PlotWidths(strcmp(allTypes,nonPrimaryTypeString{n}))); 
                    end
                    hold(app.traceDisplay,'off')
                    plot(app.corAxis,[1 totalROIs],[relatedThreshold relatedThreshold],'r:','LineWidth',1)
                    app.corAxis.YLim = [-0.1,1.1];
                    hold(app.corAxis, 'off');
                end
            catch
                disp('Must Baseline ROIs before plotting normalized values')
            end
        end
        
        function CreateMetaData(app) 
            %searches through the file title for key strings
            % This is to get the Date
            CurrName = app.CurrentName;
                if ~isempty(regexp(CurrName, '\d{6}_', 'once'))
                    tempdate = regexp(CurrName,'\d{6}_');
                    Date = CurrName(tempdate:tempdate+5);
                else
                    Date = 'N/A';
                end
            % This is to get the Researcher
                if ~isempty(regexp(CurrName, '_[A-Z]{3}_', 'once'))
                    tempresearcher = regexp(CurrName,'_[A-Z]{3}_');
                    Researcher = CurrName(tempresearcher+1:tempresearcher+3);
                else
                    Researcher = 'N/A';
                end
            % This is to get the Species of the sphere
                SpeciesArray = {'_RT_' '_HU_' '_MS_'};
                if (contains(CurrName, SpeciesArray))
                    for i=1:length(SpeciesArray)
                        if(contains(CurrName, SpeciesArray{i}))
                        Species = SpeciesArray{i};
                        end
                    end
                else
                    Species = 'N/A';
                end
            % This is to get the Size of the sphere
                if (contains(CurrName, 'K_'))
                    tempsize = strfind(CurrName,'K_');
                    previousposition = str2double(CurrName(tempsize-1));
                    if ~isnan(previousposition)
                        Size = CurrName((strfind(CurrName, 'K_')-1):strfind(CurrName, 'K_'));
                    else
                        Size = 'N/A';
                    end
                else
                    Size = 'N/A';
                end
            % This is to get the Sex
                SexArray = {'_M_' '_F_' '_X_'};
                if (contains(CurrName, SexArray))
                    for i=1:length(SexArray)
                        if(contains(CurrName, SexArray{i}))
                        Sex = SexArray{i};
                        end
                    end
                else
                Sex = 'N/A';
                end
            % This is to get the Days in Vitro
                if (contains(CurrName, '_DIV'))
                    tempDIV = strfind(CurrName,'_DIV');
                    DIV = CurrName(tempDIV+4:tempDIV+5);
                else
                    DIV = 'N/A';
                end
            % This is to get the Sphere Number
                if (contains(CurrName, '_SN'))
                    tempSN = strfind(CurrName,'_SN');
                    SphereNumber = CurrName(tempSN+3:tempSN+4);
                else
                    SphereNumber = 'N/A';
                end
            
            % This is to get the condition
                if (contains(CurrName, '_CND'))
                    tempCND = strfind(CurrName,'_CND');
                    NxtUndr = strfind(CurrName(tempCND+1:end),'_');
                    CNDend = tempCND + NxtUndr(1);
                    Condition = CurrName(tempCND+4:CNDend-1);
                else
                    Condition = 'N/A';
                end
            % This to combine it all
            app.DateYYMMDDEditField.Value = Date;
            app.YourInitialsEditField.Value = Researcher;
            app.SpeciesHuMsRtEditField.Value = Species(2:3);
            app.Size2K4K8KEditField.Value = Size;
            app.SexMFXEditField.Value= Sex(2);
            app.DaysInVitroEditField.Value = DIV;
            app.SampleNumberEditField.Value = SphereNumber;
            app.ConditionEditField.Value = Condition;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            try
                evalin('base','metaData.importPath;');
                disp('importing images ...')
                pathExists=1;
            catch
                pathExists=0;
            end
        
            % User has set a path, and wants multi-page tif.
            if pathExists==1
                imPath=evalin('base','metaData.importPath');
                tifFile=evalin('base','metaData.tifFile');
                app.mpTifInfo=evalin('base','metaData.mpTifInfo');
                firstIm=str2num(app.firstImageEditField.Value); %#ok<*ST2NM> 
                endIm=str2num(app.endImageEditField.Value);
        
            % User has set not path, and wants multi-page tif.
            elseif pathExists==0
                [tifFile,imPath]=uigetfile('*.*','Select your tif file');
                app.mpTifInfo=imfinfo([imPath tifFile]);
%                 imageCount=length(app.mpTifInfo);
                firstIm=str2num(app.firstImageEditField.Value);
                endIm=str2num(app.endImageEditField.Value);
                assignin('base','mpTifInfo',app.mpTifInfo);
                assignin('base','tifFile',tifFile);
                assignin('base','imPath',imPath);
                evalin('base','metaData.mpTifInfo=mpTifInfo;,clear ans ''mpTifInfo''')
                evalin('base','metaData.importPath=importPath;,clear ans ''importPath''')
                evalin('base','metaData.tifFile=tifFile;,clear ans ''tifFile''')
            end
        
            % always see if the user wants sequential (skipBy=1)
            skipBy=str2double(app.skipFactorEditField.Value);
        

            app.importButton.Enable = 'off';
            pause(0.00000000000000001)
            try
                
                % This gets metadata needed. 
                bitD=app.mpTifInfo(1).BitDepth;
                mImage=app.mpTifInfo(1).Width;
                nImage=app.mpTifInfo(1).Height;
                
                % now figure out what images to import.
                maxImages=length(app.mpTifInfo);
                numImages=numel(firstIm:endIm);
                
                if numImages>maxImages
                    disp('your image range is invalid will do 1:all')
                    firstIm=1;
                    endIm=maxImages-1;
                end
                imRange=firstIm:skipBy:endIm;
                
                if bitD==8 || bitD==16 || bitD==32
                    eval(['imType=''uint' num2str(bitD) '''' ';'])
                else
                    imType='Double';
                end
                
                importedStack=zeros(nImage,mImage,numel(imRange),imType);
                
                tic
                    app.feedbackLabel.Text = 'Importing ...';
                    for i=1:numel(imRange)
                        if mod(i,500)==0
                            app.importButton.Text = [num2str(i) '/' ...
                             num2str(numel(imRange))];
                            pause(0.00000000000000001)
                        else
                        end
                        importedStack(:,:,i)=imread([imPath tifFile],'Index',imRange(i));
                    end
                
                assignin('base','importedStack',importedStack)
                app.importButton.Enable = 'on';
                app.feedbackLabel.Text = {['Imported ' num2str(numImages)]; ' Images'};
                app.importButton.Text = 'Import';
                pause(0.00000000000000001)
                iT=toc;
            catch
                app.importButton.Text = 'Try Again?';
                app.importButton.Enable ='on';
                pause(0.00000000000000001);
                iT=toc;
            end
            % This loads a file list that has characters that match the filter string.
            % It should detect the bit depth and dimensions.
            
            generateMeanProj(app)

            disp(['*** done with import, which took ' num2str(iT) ' seconds'])
            refreshVarListButtonPushed(app)
            
        end

        % Button pushed function: setDirectoryButton
        function setDirectoryButtonPushed(app, event)
                try
      
                    [tifFile,imPath,~]=uigetfile('.tif');
                    
                    if ~isempty(imPath)
                        app.mpTifInfo=imfinfo([imPath tifFile],'tif');
                        imageCount=length(app.mpTifInfo);
                        app.endImageEditField.Value = num2str(imageCount);
                        
                        assignin('base','mpTifInfo',app.mpTifInfo);
                        assignin('base','importPath',imPath);
                        assignin('base','tifFile',tifFile);
                        
                        evalin('base','metaData.mpTifInfo=mpTifInfo;,clear ans mpTifInfo')
                        evalin('base','metaData.importPath=importPath;,clear ans importPath')
                        evalin('base','metaData.tifFile=tifFile;,clear ans tifFile')
                        
                        fileNameGuess=strsplit(imPath,filesep);
                        app.saveEntryEditField.Value = char([filesep fileNameGuess{end-1} filesep tifFile]);                      
                    end
                catch
                    app.saveEntryEditField.Value = 'Error in FilePath';
                end
           
                refreshVarListButtonPushed(app)
             
        end

        % Button pushed function: registerButton
        function registerButtonPushed(app, event)
                
            app.registerButton.Enable = 'off';
            if evalin('base','exist("meanProj_importedStack")')==1
                 
                tTemplate = evalin('base','meanProj_importedStack');
                assignin('base','regTemplate',im2uint16(tTemplate,'Indexed'));
            
                vars = evalin('base','who');
                app.workspaceVarListBox.Items = vars;
                refreshVarListButtonPushed(app)
                
                selections = 'importedStack';
                regStackString = char(selections);
                
                    try
                        regTemp=evalin('base','regTemplate');
    %                     tCl=whos('regTemp');
                        % todo use imType to deal with non uint16
    %                     imType=tCl.class;
                        d = char(['size(' regStackString ');']);
                        stackSize=evalin('base',d);
                        subpixelFactor=50;
                        
                        try
                            totalImagesPossible=stackSize(3);
                        catch
                            totalImagesPossible=1;
                        end
                       
                        evalin('base',['registeredTransformations=zeros(4,' num2str(totalImagesPossible) ');']);

                        app.StatusLamp.Color = [1 0.37 0.1];
                        tic
                        app.feedbackLabel.Text = {'Registration' ;'started'};
                        pause(0.0000000000001);
                        
                        for n=1:totalImagesPossible
                            imReg=evalin('base',[regStackString '(:,:,' num2str(n) ');']);
                            [out1,out2]=dftregistration(fft2(regTemp),fft2(imReg),subpixelFactor);
                            
                            assignin('base','out1',out1);
                            evalin('base',['registeredTransformations(:,' num2str(n) ')=out1;,clear out1'])
                            
                            assignin('base','out2',uint16(abs(ifft2(out2))));
                            evalin('base',[regStackString '(:,:,' num2str(n) ')=out2;,clear out2'])
                            
                            if mod(n,200)==0
                                app.registerButton.Text = [num2str(n) ...
                                 '/' num2str(totalImagesPossible)];
                                pause(0.0000000000001);
                                
                            end
                        end
    
                           
                        t=toc;
                        app.StatusLamp.Color = [0 1 0];
                        app.registerButton.Text = {'Register' ;'stacks'};
                        app.feedbackLabel.Text = {'Finished' ;'registration'};
                        app.registerButton.Enable = 'on';
                        pause(0.0000000000001);
                        
                        disp(['done with registration. it took ' num2str(t) ' seconds'])
                        refreshVarListButtonPushed(app)
                    
                    catch
                        app.feedbackLabel.Text = {'Registration' ;'failed'};
                        app.StatusLamp.Color = [1 0 0];
                    end
                    
             else
                app.feedbackLabel.Text = {'Error: No Stack'; 'Imported'};
             end
            
            app.registerButton.Enable = 'on';
        end

        % Button pushed function: deleteSelectionBtn
        function deleteSelectionBtnButtonPushed(app, event)
                        
                selections = app.workspaceVarListBox.Value;
                selectedItem=char(selections);
                evalin('base',['clear ' selectedItem]);
            
%                 if selectionsIndex~=1
%                     app.workspaceVarListBox.Value = selectionsIndex-1;
%                 else
%                     app.workspaceVarListBox.Value = 1;
%                 end
                refreshVarListButtonPushed(app)
%                 
        end

        % Button pushed function: refreshVarListButton
        function refreshVarListButtonPushed(app, event)
            
                vars = evalin('base','who');
                app.workspaceVarListBox.Items = vars;
        end

        % Button pushed function: LoadMeanProjButton
        function LoadMeanProjButtonPushed(app, event)
%       function loadMeanProjectionButton_Callback(hObject,eventdata,handles,defImage)
        % a) update w/b sliders if image type changes substantially.
        % b) update image count slider to 1 if not a stack.
        % c) update image count slider for a stack only if stack changed.
    
        % this will optionally take an argument of a specific image to plot.
        er1 = evalin('base','exist("meanProj_importedStack")');    
        if er1 == 1   

            imageP=evalin('base','meanProj_importedStack'); %$% 
%             imClass=class(imageP);
%             imSize=size(imageP);
            
   
            mPix = im2double(imageP,'indexed');  
            maxpixel = max(max(mPix));
         
            if maxpixel>app.MAXKnob.Limits(2)
                 app.MAXKnob.Limits = [1 maxpixel];
                 app.MAXKnob.Value = round(maxpixel);
                 b = round(maxpixel);
            else
                 b = round(app.MAXKnob.Value);
            end
        
            app.mPiS_ImOb = imshow(imageP,[0 b],'Parent',app.ImageWindow);
            app.ImageWindow.XLim = [1 size(imageP,2)];
            app.ImageWindow.YLim = [1 size(imageP,1)];
            
            %% Creates histogram of pixel intensity values
            if numel(find(imageP)>0)>2  
                histogram(app.imageHistogram,nonzeros(imageP),40);
            else
                histogram(app.imageHistogram,zeros(80,1),40);
            end
            
            app.imageHistogram.XLim = [0 b];
            app.imageHistogram.XTickLabel = char('0', num2str(b/2), num2str(b));
            app.imageHistogram.XTick = [0,b/2,b];
            %plots max (blue) and min (red) lines
            hold(app.imageHistogram,'on');
            c = app.imageHistogram.YLim(2);
            plot(app.imageHistogram,[1 1],[0 c],'r-');
            plot(app.imageHistogram,[b b],[0 c],'b-');
            hold(app.imageHistogram,'off');

            roisDisplayToggle(app,1);
        else
            fprintf('ERROR - NEED Mean Projection of Imported Stack')
        end
    %     refreshVarListButtonPushed(app);

        end

        % Button pushed function: somaButton
        function somaButtonPushed(app, event)
            freehandROI(app,'somatic');
        end

        % Button pushed function: axonButton
        function axonButtonPushed(app, event)
            freehandROI(app,'axonal');
        end

        % Button pushed function: dendriteButton
        function dendriteButtonPushed(app, event)
            freehandROI(app,'dendritic');
        end

        % Button pushed function: deleteSelectedROI
        function deleteSelectedROIButtonPushed(app, event)
            boxNum=app.roiSelector.Value;
            specifiedString=char(boxNum);
        
            stringSplit=strsplit(specifiedString,'_');
        
            typeString=stringSplit{1};
            roiNumber=fix(str2double(stringSplit{2}));
            deleteROI(app,roiNumber,{typeString});
        
            roisDisplayToggle(app)
           
            app.roiSelector.Value = app.roiSelector.Items(1);
        
            roisDisplayToggle(app)
            refreshVarListButtonPushed(app);
        end

        % Value changed function: highCutEntry
        function highCutEntryValueChanged(app, event)
            app.MAXKnob.Value = str2double(app.highCutEntry.Value);
            LoadMeanProjButtonPushed(app);
        end

        % Value changed function: MAXKnob
        function MAXKnobValueChanged(app, event)
            app.highCutEntry.Value = num2str(round(app.MAXKnob.Value));
            LoadMeanProjButtonPushed(app);
        end

        % Value changed function: roiSelector
        function roiSelectorValueChanged(app, event)
            %does different things, depending on whether you're on
            %the extractor tab, or the ROImaker tab.
            tb=app.TabGroup.SelectedTab.Title;
            tbcheck = strncmp(tb,'Extract',7);
            if tbcheck == 0
                %if on ROI maker, highlight the selected ROI in red
                boxNum=app.roiSelector.Value;
            
                if numel(boxNum)>0
                    specifiedStrings=cell(1,numel(boxNum));
                    for n=1:numel(boxNum)
                        specifiedStrings{n}={boxNum};
                    end
                    k = numel(specifiedStrings);
                    %plot the ROIs as you normally would
                    hold(app.ImageWindow,'on');
                    roisDisplayToggle(app)
    
                    %Then, set selected ROI(s) to red
                    roiBoundaries = cell(1,k);
                    for n=1:k   
                        stringSplit=strsplit(char(specifiedStrings{n}),'_');
                        typeString=stringSplit{1};
                        roiNumber=fix(str2double(stringSplit{2}));
                        roiBoundaries{n}=evalin('base',[typeString 'ROIBoundaries([' num2str(roiNumber) ']);']); 
                        b=roiBoundaries{n};
                        
                        hold(app.ImageWindow,'on');
                        plot(app.ImageWindow,b{1,1}{1,1}(:,2),b{1,1}{1,1}(:,1),'Color',[1 0 0],'LineWidth',2.5)
                    end             
                    
                    hold(app.ImageWindow,'off');
                    refreshVarListButtonPushed(app);
                end 
            else
                %&% If on extractor tab, plot the fluorescence  
                plotROI(app)
            end
        end

        % Value changed function: somaticCheckBox
        function somaticCheckBoxValueChanged(app, event)
            roisDisplayToggle(app);            
        end

        % Value changed function: axonalCheckBox
        function axonalCheckBoxValueChanged(app, event)
            roisDisplayToggle(app); 
        end

        % Value changed function: dendriticCheckBox
        function dendriticCheckBoxValueChanged(app, event)
            roisDisplayToggle(app); 
        end

        % Button pushed function: UpdateMeanProjButton
        function UpdateMeanProjButtonPushed(app, event)
            generateMeanProj(app);
        end

        % Button pushed function: extractButton
        function extractButtonPushed(app, event)
            selectStack = 'importedStack';
            dStackSize=evalin('base','size(importedStack);');
        
            if dStackSize(3)>1
        
                % ************ handle concatination of roi types
                roiStringMap={'somaticROIs','dendriticROIs','axonalROIs'};
                roiToggleTruth=[app.somaExtractCheck.Value, app.dendriteExtractCheck.Value, ...
                    app.axonExtractCheck.Value];
            
                rois=[];   % we will map in the selected rois into this
                warnBit=1;
                for n=1:numel(roiToggleTruth)
                    if roiToggleTruth(n)==1
                        warnBit=0;      % if anything is selected flip the warning to 0.
                        roisToMap=evalin('base', roiStringMap{n});
                        rois=[rois roisToMap];
                    else
                    end
                end
            
                if warnBit==1
                    disp('no rois')
                end
                % ************ end handle concatination of roi types
            
                %--- extract
                app.extractButton.Text = 'running';
                app.extractButton.Enable = 'off';
                pause(0.00000001);
            
                sED=zeros(numel(rois),dStackSize(3));
                tic
            
                for n=1:dStackSize(3)
                    for q=1:numel(rois)
                        aIm=double(evalin('base',[selectStack '(:,:,' num2str(n) ')']));
                        sED(q,n)=mean(aIm(rois{q}(:,:)==1));
                    end
                    if mod(n,500)==0
                        app.extractButton.Text = [num2str(n) '/' num2str(dStackSize(3))];
                        pause(0.00000000000000001)
                    else
                    end
                end
            
                toc;
                app.extractButton.Text = 'Extract';
            
                %--- end extract
            
                % ************  now we need to map the extracted values to the original roi types
                for n=1:numel(roiToggleTruth)
                    if roiToggleTruth(n)==1
                        roisToMapCount=evalin('base', ['numel(' roiStringMap{n} ')']);
                        assignin('base',[roiStringMap{n}(1:end-4) 'F'],sED(1:roisToMapCount,:));
                        sED(1:roisToMapCount,:)=[];
                    else
                    end
                end
            
            
                app.extractButton.Enable = 'on';
            
                % Update handles structure
                app.extractFeedbackString.Text = {'Extraction'; 'Complete'};
            
                % Update handles structure
                refreshVarListButtonPushed(app)
            end
                    
        end

        % Button pushed function: CLEARALLVARIABLESButton
        function CLEARALLVARIABLESButtonPushed(app, event)
            if app.AreyousureCheckBox.Value == 1
                evalin('base','clear')
            else
                disp('Are you SURE you want to clear? If yes, enable clear safety')
            end
            refreshVarListButtonPushed(app)
            app.roiSelector.Items = {};
            
            app.DateYYMMDDEditField.Value = 'N/A';
            app.YourInitialsEditField.Value = 'N/A';
            app.SpeciesHuMsRtEditField.Value = 'N/A';
            app.Size2K4K8KEditField.Value = 'N/A';
            app.SexMFXEditField.Value= 'N/A';
            app.DaysInVitroEditField.Value = 'N/A';
            app.SampleNumberEditField.Value = 'N/A';
            app.ConditionEditField.Value = 'ctl';
            app.DurationofRecordingsEditField.Value = '60';
            app.HeightEditField.Value = '1';
            app.WidthEditField.Value = '1';
            app.ofFramesEditField.Value = '1';
        end

        % Value changed function: AreyousureCheckBox
        function AreyousureCheckBoxValueChanged(app, event)
            if app.AreyousureCheckBox.Value == 1
                app.CLEARALLVARIABLESButton.Enable = 'on';
            else
                app.CLEARALLVARIABLESButton.Enable = 'off';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %&% Enter SaveTheROIs Program Here
            try
                SaveItTo = evalin('base','[metaData.importPath metaData.tifFile(1:end-4)]');                
            catch
                    app.SaveFeedbackString.Text = {'metaData structure not detected.';'Searching for alternatives...'};
                    app.SaveFeedbackString.FontColor = [0 0 0];
                try
                    FilePath = uigetdir('C:\Users\jess7\Documents\Grad_School\DHK_Labtime\ConfocalImages\','Select Folder to Save data');
                    TifName = evalin('base','fMetaData.name(1:end-4)');
                    SaveItTo = [FilePath TifName];
                catch
                    app.SaveFeedbackString.Text = 'Error - No MetaData detected';
                    app.SaveFeedbackString.FontColor = [1 0 0];
                end
            end
            
                fMetaData.Date = app.DateYYMMDDEditField.Value;
                fMetaData.Researcher = app.YourInitialsEditField.Value;
                fMetaData.Species = app.SpeciesHuMsRtEditField.Value;
                fMetaData.Size = app.Size2K4K8KEditField.Value;
                fMetaData.Sex = app.SexMFXEditField.Value;
                fMetaData.Days_In_Vitro = app.DaysInVitroEditField.Value;
                fMetaData.Sphere_Number = app.SampleNumberEditField.Value;
                fMetaData.Condition = app.ConditionEditField.Value;
                fMetaData.width = str2double(app.WidthEditField.Value);
                fMetaData.height = str2double(app.HeightEditField.Value);
                fMetaData.frames = str2double(app.ofFramesEditField.Value);
                fMetaData.numsecs = str2double(app.DurationofRecordingsEditField.Value);
                fMetaData.name = app.CurrentName;
                
            try
                %metaData is structure from Deister GUI. Last 4 chars are filetype- dont
                save(SaveItTo, 'fMetaData');
                
                for i=1:numel(app.returnTypeStrings)
                    TypeString = app.returnTypeStrings{i};
                    s = evalin('base',['exist("' TypeString 'F","var")']);
                    if s==1 %check for ROIs of that type
                        suffixes = {'F', 'F_DF', 'F_nonBL', 'ROI_PixelLists' ...
                            'ROIBoundaries', 'ROICenters', 'RoiCounter', 'ROIs'}
                        for i = 1:numel(suffixes)
                            a = [TypeString suffixes{i}];
                            bigstruct.(a) = evalin('base', [TypeString suffixes{i}]);
                        end
                    end
                end
                
                save(SaveItTo, '-struct', 'bigstruct','-append');
                app.SaveFeedbackString.Text = {'Data Saved!';'Excellent work';'';'PS: Remember to back it up!'};
                app.SaveFeedbackString.FontColor = [0.18,0.68,0.06];
                
            catch
                app.SaveFeedbackString.FontColor = [1 0 0];
                app.SaveFeedbackString.Text = 'Error in saving';
            end
            

        end

        % Value changed function: somaticROIs_DisplayToggle
        function somaticROIs_DisplayToggleValueChanged(app, event)
            genericDisplayTypeToggle(app)
        end

        % Value changed function: dendriticROIs_DisplayToggle
        function dendriticROIs_DisplayToggleValueChanged(app, event)
%             value = app.dendriticROIs_DisplayToggle.Value;
            genericDisplayTypeToggle(app)
        end

        % Value changed function: axonalROIs_DisplayToggle
        function axonalROIs_DisplayToggleValueChanged(app, event)
%             value = app.axonalROIs_DisplayToggle.Value;
            genericDisplayTypeToggle(app)
        end

        % Button pushed function: slidingBaselineBtn
        function slidingBaselineBtnButtonPushed(app, event)

            pause(0.0000000000001);
            frmWinSize=app.MoveBaselineWinEntry.Value;
            tString=app.typeSelectorMenu.Value;
        
            selectedType=char(tString);
            app.feedbackString_2.Text = {'Baselining: '; selectedType};
            pause(0.0000000000001);

            try
                ogData = evalin('base','somaticF_original');
            catch
                evalin('base','somaticF_original = somaticF;');
                ogData = evalin('base','somaticF_original');
            end
            fData = evalin('base',[selectedType 'F']);
            fData = fData + 10000;
            scaleError = 10000/nanmean(nanmean(ogData));
        
            app.fBLs = zeros(size(fData));
            
            computeQuantileCutoffs(app,fData); %F_BLCutOffs
            blCutOffs = app.quantileCutoffs;
        
            slidingBaseline(app,fData,frmWinSize,blCutOffs);

            assignin('base',[selectedType 'F_nonBL'],fData)
        
            fData = fData - app.fBLs;
            dfTemp = fData./app.fBLs;
        
            assignin('base',[selectedType 'F'],fData)
            assignin('base',[selectedType 'F_DF'],dfTemp*scaleError)
        
            app.feedbackString_2.Text = {selectedType ; 'Complete'};
            pause(0.0000000000001);
            
            plotROI(app)
            refreshVarListButtonPushed(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            genericDisplayTypeToggle(app)
        end

        % Value changed function: yHighTrace
        function yHighTraceValueChanged(app, event)
            app.traceDisplay.YLim(2) = str2double(app.yHighTrace.Value);
        end

        % Value changed function: yLowTrace
        function yLowTraceValueChanged(app, event)
            app.traceDisplay.YLim(1) = str2double(app.yLowTrace.Value);
        end

        % Value changed function: plotAsDFToggle
        function plotAsDFToggleValueChanged(app, event)
            plotROI(app);
        end

        % Button pushed function: DetectMetaDataButton
        function DetectMetaDataButtonPushed(app, event)
                        
            try
                app.CurrentName = evalin('base','upper(metaData.tifFile)');
                app.WidthEditField.Value = num2str(evalin('base','metaData.mpTifInfo(1).Width'));
                app.HeightEditField.Value = num2str(evalin('base','metaData.mpTifInfo(1).Height'));
                app.ofFramesEditField.Value = num2str(evalin('base','length(metaData.mpTifInfo)'));
            catch
                disp('Variable metaData not detected - searching for existing fMetaData')
                
                try
                    app.CurrentName = evalin('base','upper(fMetaData.name');
                    app.WidthEditField.Value = num2str(evalin('base','fmetaData.width'));
                    app.HeightEditField.Value = num2str(evalin('base','fmetaData.height'));
                    app.ofFramesEditField.Value = num2str(evalin('base','fmetaData.frames'));
                catch
                    disp('No MetaData Sources Detected')
                    app.CurrentName = 'UNKNOWN';
                end
            end
                      
            
            CreateMetaData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportFilesTab = uitab(app.TabGroup);
            app.ImportFilesTab.Title = 'ImportFiles';
            app.importPanel = uipanel(app.ImportFilesTab);
            app.importPanel.Title = 'Import File';
            app.setDirectoryButton = uibutton(app.importPanel, 'push');
            app.setDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @setDirectoryButtonPushed, true);
            app.setDirectoryButton.Text = 'Locate File';
            app.importButton = uibutton(app.importPanel, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'Import';
            app.feedbackLabel = uilabel(app.importPanel);
            app.feedbackLabel.Text = '';
            app.StepLabel = uilabel(app.importPanel);
            app.StepLabel.Text = {'Mean Projection'; 'should automatically '; 'appear on right'};
            app.StepLabel_2 = uilabel(app.importPanel);
            app.StepLabel_2.Text = '1: Locate Tiff stack';
            app.StepLabel_6 = uilabel(app.importPanel);
            app.StepLabel_6.Text = '2: Import';
            app.uipanel3 = uipanel(app.ImportFilesTab);
            app.uipanel3.Title = 'IF there is X-Y motion:';
            app.registerButton = uibutton(app.uipanel3, 'push');
            app.registerButton.ButtonPushedFcn = createCallbackFcn(app, @registerButtonPushed, true);
            app.registerButton.Text = 'Register';
            app.StepLabel_4 = uilabel(app.uipanel3);
            app.StepLabel_4.Text = {'Register your stack'; '(this may take several mins)'};
            app.StatusLampLabel = uilabel(app.uipanel3);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.uipanel3);
            app.UpdateMeanProjButton = uibutton(app.uipanel3, 'push');
            app.UpdateMeanProjButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMeanProjButtonPushed, true);
            app.UpdateMeanProjButton.Text = 'Update Mean Proj';
            app.StepLabel_7 = uilabel(app.uipanel3);
            app.StepLabel_7.Text = 'Optional:';
            app.importPanel_2 = uipanel(app.ImportFilesTab);
            app.importPanel_2.Title = 'Preview Mean Projection:';
            app.imageAxis = uiaxes(app.importPanel_2);
            app.ExtrasPanel = uipanel(app.ImportFilesTab);
            app.ExtrasPanel.Title = 'Extras:';
            app.skipFactorEditField = uieditfield(app.ExtrasPanel, 'text');
            app.skipFactorEditField.Value = '1';
            app.SkipByLabel = uilabel(app.ExtrasPanel);
            app.SkipByLabel.Text = 'skip by:';
            app.pickFrameLabel = uilabel(app.ExtrasPanel);
            app.pickFrameLabel.Text = '(Optional) Pick which frames to import:';
            app.firstImageEditField = uieditfield(app.ExtrasPanel, 'text');
            app.firstImageEditField.Value = '1';
            app.startNumLabel = uilabel(app.ExtrasPanel);
            app.startNumLabel.Text = 'start';
            app.startToLabel = uilabel(app.ExtrasPanel);
            app.startToLabel.Text = 'to';
            app.endImageEditField = uieditfield(app.ExtrasPanel, 'text');
            app.endImageEditField.Value = '2';
            app.saveEntryEditField = uieditfield(app.ExtrasPanel, 'text');
            app.saveEntryEditField.Value = '/path/imageName/dataSet';
            app.StepLabel_5 = uilabel(app.ExtrasPanel);
            app.StepLabel_5.Text = '(Sanity Check) file name should appear here:';
            app.MakeROIsTab = uitab(app.TabGroup);
            app.MakeROIsTab.Title = 'MakeROIs';
            app.uipanel11 = uipanel(app.MakeROIsTab);
            app.uipanel11.Title = 'Image/Video Display';
            app.highCutEntry = uieditfield(app.uipanel11, 'text');
            app.highCutEntry.ValueChangedFcn = createCallbackFcn(app, @highCutEntryValueChanged, true);
            app.highCutEntry.Value = '1000';
            app.feedbackString = uilabel(app.uipanel11);
            app.feedbackString.Text = '';
            app.LoadMeanProjButton = uibutton(app.uipanel11, 'push');
            app.LoadMeanProjButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMeanProjButtonPushed, true);
            app.LoadMeanProjButton.Text = 'Load Mean Proj';
            app.drawassistCheckBox = uicheckbox(app.uipanel11);
            app.drawassistCheckBox.Text = 'draw assist?';
            app.uipanel6 = uipanel(app.uipanel11);
            app.uipanel6.Title = 'Make ROIs';
            app.somaButton = uibutton(app.uipanel6, 'push');
            app.somaButton.ButtonPushedFcn = createCallbackFcn(app, @somaButtonPushed, true);
            app.somaButton.Text = 'Soma';
            app.dendriteButton = uibutton(app.uipanel6, 'push');
            app.dendriteButton.ButtonPushedFcn = createCallbackFcn(app, @dendriteButtonPushed, true);
            app.dendriteButton.Text = 'Dendrite';
            app.axonButton = uibutton(app.uipanel6, 'push');
            app.axonButton.ButtonPushedFcn = createCallbackFcn(app, @axonButtonPushed, true);
            app.axonButton.Text = 'Axon';
            app.somaticCheckBox = uicheckbox(app.uipanel6);
            app.somaticCheckBox.ValueChangedFcn = createCallbackFcn(app, @somaticCheckBoxValueChanged, true);
            app.somaticCheckBox.Text = 'Show?';
            app.axonalCheckBox = uicheckbox(app.uipanel6);
            app.axonalCheckBox.ValueChangedFcn = createCallbackFcn(app, @axonalCheckBoxValueChanged, true);
            app.axonalCheckBox.Text = 'Show?';
            app.dendriticCheckBox = uicheckbox(app.uipanel6);
            app.dendriticCheckBox.ValueChangedFcn = createCallbackFcn(app, @dendriticCheckBoxValueChanged, true);
            app.dendriticCheckBox.Text = 'Show?';
            app.MAXKnobLabel = uilabel(app.uipanel11);
            app.MAXKnobLabel.Text = 'MAX';
            app.MAXKnob = uiknob(app.uipanel11, 'continuous');
            app.MAXKnob.Limits = [0 1000];
            app.MAXKnob.ValueChangedFcn = createCallbackFcn(app, @MAXKnobValueChanged, true);
            app.MAXKnob.Value = 1000;
            app.ImageWindow = uiaxes(app.uipanel11);
            title(app.ImageWindow, '')
            xlabel(app.ImageWindow, '')
            ylabel(app.ImageWindow, '')
            app.ImageWindow.XTick = [];
            app.ImageWindow.YTick = [];
            app.imageHistogram = uiaxes(app.uipanel11);
            title(app.imageHistogram, '')
            xlabel(app.imageHistogram, '')
            ylabel(app.imageHistogram, '')
            app.imageHistogram.XTick = [0 0.5 1];
            app.imageHistogram.YTickLabel = '';
            app.ExtractFluorescenceTab = uitab(app.TabGroup);
            app.ExtractFluorescenceTab.Title = 'ExtractFluorescence';
            app.uipanel1 = uipanel(app.ExtractFluorescenceTab);
            app.uipanel1.Title = 'ROIs to Extract';
            app.extractFeedbackString = uilabel(app.uipanel1);
            app.extractFeedbackString.Text = '';
            app.somaExtractCheck = uicheckbox(app.uipanel1);
            app.somaExtractCheck.Text = 'Somatic';
            app.somaExtractCheck.Value = true;
            app.dendriteExtractCheck = uicheckbox(app.uipanel1);
            app.dendriteExtractCheck.Text = 'Dendritic';
            app.axonExtractCheck = uicheckbox(app.uipanel1);
            app.axonExtractCheck.Text = 'Axonal';
            app.extractButton = uibutton(app.uipanel1, 'push');
            app.extractButton.ButtonPushedFcn = createCallbackFcn(app, @extractButtonPushed, true);
            app.extractButton.Text = 'Extract';
            app.uipanel9 = uipanel(app.ExtractFluorescenceTab);
            app.uipanel9.Title = 'Baseline:';
            app.slidingBaselineBtn = uibutton(app.uipanel9, 'push');
            app.slidingBaselineBtn.ButtonPushedFcn = createCallbackFcn(app, @slidingBaselineBtnButtonPushed, true);
            app.slidingBaselineBtn.Text = 'Baseline';
            app.useQuantForDFToggle = uicheckbox(app.uipanel9);
            app.useQuantForDFToggle.Enable = 'off';
            app.useQuantForDFToggle.Text = 'use quant threshold';
            app.useQuantForDFToggle.Value = true;
            app.typeSelectorMenu = uidropdown(app.uipanel9);
            app.typeSelectorMenu.Items = {'somatic', 'dendritic', 'axonal'};
            app.typeSelectorMenu.Value = 'somatic';
            app.feedbackString_2 = uilabel(app.uipanel9);
            app.feedbackString_2.Text = '';
            app.MoveBaselineWinEntry = uieditfield(app.uipanel9, 'numeric');
            app.MoveBaselineWinEntry.Limits = [0 Inf];
            app.MoveBaselineWinEntry.RoundFractionalValues = 'on';
            app.MoveBaselineWinEntry.Value = 500;
            app.uipanel20 = uipanel(app.ExtractFluorescenceTab);
            app.uipanel20.Title = 'Preview:';
            app.traceDisplay = uiaxes(app.uipanel20);
            app.traceDisplay.YLim = [0 65535];
            app.yHighTrace = uieditfield(app.uipanel20, 'text');
            app.yHighTrace.ValueChangedFcn = createCallbackFcn(app, @yHighTraceValueChanged, true);
            app.yHighTrace.Value = '2';
            app.yLowTrace = uieditfield(app.uipanel20, 'text');
            app.yLowTrace.ValueChangedFcn = createCallbackFcn(app, @yLowTraceValueChanged, true);
            app.yLowTrace.Value = '-0.1';
            app.YmaxLabel = uilabel(app.uipanel20);
            app.YmaxLabel.Text = 'Y max';
            app.YminLabel = uilabel(app.uipanel20);
            app.YminLabel.Text = 'Y min';
            app.uipanel5 = uipanel(app.uipanel20);
            app.uipanel5.Title = 'Display:';
            app.somaticROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.somaticROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @somaticROIs_DisplayToggleValueChanged, true);
            app.somaticROIs_DisplayToggle.Text = 'somatic';
            app.somaticROIs_DisplayToggle.Value = true;
            app.dendriticROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.dendriticROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @dendriticROIs_DisplayToggleValueChanged, true);
            app.dendriticROIs_DisplayToggle.Text = 'dendritic';
            app.axonalROIs_DisplayToggle = uicheckbox(app.uipanel5);
            app.axonalROIs_DisplayToggle.ValueChangedFcn = createCallbackFcn(app, @axonalROIs_DisplayToggleValueChanged, true);
            app.axonalROIs_DisplayToggle.Text = 'axonal';
            app.plotAsDFToggle = uicheckbox(app.uipanel5);
            app.plotAsDFToggle.ValueChangedFcn = createCallbackFcn(app, @plotAsDFToggleValueChanged, true);
            app.plotAsDFToggle.Text = 'use df/f?';
            app.plotAsDFToggle.Value = true;
            app.uipanel7 = uipanel(app.ExtractFluorescenceTab);
            app.uipanel7.Title = 'Correlations/Relations';
            app.corAxis = uiaxes(app.uipanel7);
            app.corAxis.XTick = [];
            app.corAxis.XTickLabel = {'0'; '0.5'; '1'};
            app.corAxis.YTick = [];
            app.corAxis.YTickLabel = {'0.0'; '0.2'; '0.4'; '0.6'; '0.8'; '1.0'};
            app.showCorrelatedToggle = uicheckbox(app.uipanel7);
            app.showCorrelatedToggle.Text = 'show related?';
            app.corThresholdEntry = uieditfield(app.uipanel7, 'text');
            app.corThresholdEntry.Value = '0.5';
            app.text9 = uilabel(app.uipanel7);
            app.text9.Text = 'thresh';
            app.pushbutton38 = uibutton(app.uipanel7, 'push');
            app.pushbutton38.Text = 'refresh';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DateYYMMDDLabel = uilabel(app.SaveTab);
            app.DateYYMMDDLabel.Text = {'Date '; '(YYMMDD)'};
            app.DateYYMMDDEditField = uieditfield(app.SaveTab, 'text');
            app.DateYYMMDDEditField.Value = 'N/A';
            app.YourInitialsEditFieldLabel = uilabel(app.SaveTab);
            app.YourInitialsEditFieldLabel.Text = 'Your Initials';
            app.YourInitialsEditField = uieditfield(app.SaveTab, 'text');
            app.YourInitialsEditField.Value = 'JLS';
            app.SpeciesHuMsRtEditFieldLabel = uilabel(app.SaveTab);
            app.SpeciesHuMsRtEditFieldLabel.Text = {'Species'; '(Hu, Ms, Rt)'};
            app.SpeciesHuMsRtEditField = uieditfield(app.SaveTab, 'text');
            app.SpeciesHuMsRtEditField.Value = 'N/A';
            app.SexMFXLabel = uilabel(app.SaveTab);
            app.SexMFXLabel.Text = {'Sex'; '(M / F / X)'};
            app.SexMFXEditField = uieditfield(app.SaveTab, 'text');
            app.SexMFXEditField.Value = 'N/A';
            app.Size2K4K8KEditFieldLabel = uilabel(app.SaveTab);
            app.Size2K4K8KEditFieldLabel.Text = {'Size'; '(2K, 4K, 8K)'};
            app.Size2K4K8KEditField = uieditfield(app.SaveTab, 'text');
            app.Size2K4K8KEditField.Value = 'N/A';
            app.DaysInVitroLabel = uilabel(app.SaveTab);
            app.DaysInVitroLabel.Text = {'Days '; 'In Vitro'};
            app.DaysInVitroEditField = uieditfield(app.SaveTab, 'text');
            app.DaysInVitroEditField.Value = 'N/A';
            app.SampleNumberEditFieldLabel = uilabel(app.SaveTab);
            app.SampleNumberEditFieldLabel.Text = {'Sample'; 'Number'};
            app.SampleNumberEditField = uieditfield(app.SaveTab, 'text');
            app.SampleNumberEditField.Value = 'N/A';
            app.ConditionEditFieldLabel = uilabel(app.SaveTab);
            app.ConditionEditFieldLabel.Text = 'Condition';
            app.ConditionEditField = uieditfield(app.SaveTab, 'text');
            app.ConditionEditField.Value = 'ctl';
            app.DetectMetaDataButton = uibutton(app.SaveTab, 'push');
            app.DetectMetaDataButton.ButtonPushedFcn = createCallbackFcn(app, @DetectMetaDataButtonPushed, true);
            app.DetectMetaDataButton.Text = 'Detect MetaData';
            app.DurationofRecordingsEditFieldLabel = uilabel(app.SaveTab);
            app.DurationofRecordingsEditFieldLabel.Text = {'Duration of'; 'Recording (s)'};
            app.DurationofRecordingsEditField = uieditfield(app.SaveTab, 'text');
            app.DurationofRecordingsEditField.Value = '60';
            app.WidthEditFieldLabel = uilabel(app.SaveTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.SaveTab, 'text');
            app.WidthEditField.Editable = 'off';
            app.WidthEditField.Value = '1';
            app.HeightEditFieldLabel = uilabel(app.SaveTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.SaveTab, 'text');
            app.HeightEditField.Editable = 'off';
            app.HeightEditField.Value = '1';
            app.ofFramesEditField_2Label = uilabel(app.SaveTab);
            app.ofFramesEditField_2Label.Text = {'# of '; 'Frames'};
            app.ofFramesEditField = uieditfield(app.SaveTab, 'text');
            app.ofFramesEditField.Editable = 'off';
            app.ofFramesEditField.Value = '1';
            app.AFTERsavingdataclearallPanel = uipanel(app.SaveTab);
            app.AFTERsavingdataclearallPanel.Title = 'AFTER saving data, clear all';
            app.AreyousureCheckBox = uicheckbox(app.AFTERsavingdataclearallPanel);
            app.AreyousureCheckBox.ValueChangedFcn = createCallbackFcn(app, @AreyousureCheckBoxValueChanged, true);
            app.AreyousureCheckBox.Text = 'Are you sure?';
            app.CLEARALLVARIABLESButton = uibutton(app.AFTERsavingdataclearallPanel, 'push');
            app.CLEARALLVARIABLESButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARALLVARIABLESButtonPushed, true);
            app.CLEARALLVARIABLESButton.Enable = 'off';
            app.CLEARALLVARIABLESButton.Text = {'CLEAR  ALL'; 'VARIABLES'};
            app.ThisactioncannotbeundoneLabel = uilabel(app.AFTERsavingdataclearallPanel);
            app.ThisactioncannotbeundoneLabel.Text = 'This action cannot be undone';
            app.WillDisplaybelowIfneededfixbeforesavingLabel = uilabel(app.SaveTab);
            app.WillDisplaybelowIfneededfixbeforesavingLabel.Text = {'Will Display below.'; 'If needed, fix before saving'};
            app.SaveMetaDatafieldsastheyappearaboveLabel = uilabel(app.SaveTab);
            app.SaveMetaDatafieldsastheyappearaboveLabel.Text = {'Save MetaData fields'; 'as they appear above?'};
            app.SaveFeedbackString = uilabel(app.SaveTab);
            app.SaveFeedbackString.Text = {'Detect MetaData,'; 'Then Save  '};
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'INSTRUCTIONS:'; ''; 'I) We begin our journey on the ''Import Files'' tab. '; ''; '1)You will need the tiff stack from your calcium imaging session. I strongly suggest storing this file locally (on your device) for speed purposes.'; ''; '*Note: this program is NOT equipped to handle other image formats. It must be a three-dimensional Tiff, where the third dimension is time. '; ''; '2) Press the ''Locate File'' button. A dialogue box will pop up. Navigate to your image of choice.'; ''; '3)  The file name of the image should pop up onthe ''sanity check'' string. Make sure it is correct, then press ''Import'''; ''; '4) A mean projection of your image will appear, in the colorscheme ''Jet'' (red is bright, yellow is medium, and blue is dim)'; ''; '5) if you have XY-motion (ie, if your sample is jiggly in the video) press ''Register.'' This will take a moment. Ex: for a 1500-frame image, I usually expect to wait 2 minutes. The larger the file, the longer the wait. '; '*If you don''t have XY-motion, I wouldn''t bother.'; '**Update your mean projection after this. If it looks weird, stop and find someone more experienced.'; ''; 'II) You may now move to the ''Make ROIs'' tab.'; ''; '1) The first thing you''ll want to do is load your mean projection. (Top left button)'; ''; '2) A grayscale version of your image should pop up. Use the knob at the bottom to adjust brightness to desired level. '; 'TIP: If you hover over the upper-right-hand corner of the window, Zoom In, Zoom Out, Pan, and Reset View tools become visible. Use these to manipulate the image while you draw your ROIs.'; ''; '3) Select the kind of ROI you want to draw (usually Soma) and freehand click and drag.'; '*If you don''t like an ROI, you can delete it on the ROI browser to your left.'; '** Assisted-draw ROI is a method MatLab uses edges it detects in the image to guess where you want to draw your ROI. Try it out, see if you like it. It requires multiple clicks rather than click and drag.'; ''; '4) If you''re using the Sevetson analysis program, your last 2 ROIs should be background. These allow you to check for noise later.'; '- (2nd to last) an ROI of the entire spheroids'; '- (last) an ROI of the background, with NO spheroid'; ''; 'III) When you''re happy with your ROIs, move to the Extract tab.'; ''; '1) '};
            app.WorkspacePanel = uipanel(app.UIFigure);
            app.WorkspacePanel.Title = 'Workspace';
            app.workspaceVarListBox = uilistbox(app.WorkspacePanel);
            app.workspaceVarListBox.Items = {};
            app.workspaceVarListBox.Multiselect = 'on';
            app.workspaceVarListBox.Value = {};
            app.refreshVarListButton = uibutton(app.WorkspacePanel, 'push');
            app.refreshVarListButton.ButtonPushedFcn = createCallbackFcn(app, @refreshVarListButtonPushed, true);
            app.refreshVarListButton.Text = 'Refresh';
            app.deleteSelectionBtn = uibutton(app.WorkspacePanel, 'push');
            app.deleteSelectionBtn.ButtonPushedFcn = createCallbackFcn(app, @deleteSelectionBtnButtonPushed, true);
            app.deleteSelectionBtn.Text = 'Delete';
            app.uipanel13 = uipanel(app.UIFigure);
            app.uipanel13.Title = 'ROI Browser';
            app.roiSelector = uilistbox(app.uipanel13);
            app.roiSelector.Items = {'Rois'};
            app.roiSelector.ValueChangedFcn = createCallbackFcn(app, @roiSelectorValueChanged, true);
            app.roiSelector.Value = 'Rois';
            app.deleteSelectedROI = uibutton(app.uipanel13, 'push');
            app.deleteSelectedROI.ButtonPushedFcn = createCallbackFcn(app, @deleteSelectedROIButtonPushed, true);
            app.deleteSelectedROI.Text = 'Delete ROI';
            app.RefreshButton = uibutton(app.uipanel13, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TiffToMat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end